classdef FittingOptionsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FittingParametersUIFigure  matlab.ui.Figure
        CalculatedParametersTable  matlab.ui.control.Table
        CalculatedParametersLabel  matlab.ui.control.Label
        StartingParameterEstimatesLabel  matlab.ui.control.Label
        StartingParametersTable    matlab.ui.control.Table
        UpdateStartingParameterEstimatesButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        GelBoxApp % Description
        n
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            movegui(app.FittingParametersUIFigure,'center')
            
            app.GelBoxApp = caller;
            app.n = str2num(caller.BoxSelectionDropDown.Value);
            t = struct2table(caller.gel_data.par_est(app.n));
            app.StartingParametersTable.Data = t;
            
            if ~isempty(caller.gel_data.par_fit(app.n).band_no)
                app.CalculatedParametersLabel.Enable = 1;
                app.CalculatedParametersTable.Enable = 'on';
                t2 = struct2table(caller.gel_data.par_fit(app.n));
                app.CalculatedParametersTable.Data = t2;
            end
            

        end

        % Button pushed function: 
        % UpdateStartingParameterEstimatesButton
        function UpdateStartingParameterEstimatesButtonPushed(app, event)

            UpdateFittingOptions(app.GelBoxApp);
            
            delete(app)
        end

        % Cell edit callback: StartingParametersTable
        function StartingParametersTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            new_param = table2struct(app.StartingParametersTable.Data);
%             app.GelBoxApp.gel_data.par_est(app.n) = [];
            app.GelBoxApp.gel_data.par_est(app.n).band_no = app.StartingParametersTable.Data.band_no;
            app.GelBoxApp.gel_data.par_est(app.n).peak_location = app.StartingParametersTable.Data.peak_location;
            app.GelBoxApp.gel_data.par_est(app.n).shape_parameter = app.StartingParametersTable.Data.shape_parameter;
            app.GelBoxApp.gel_data.par_est(app.n).amplitude = app.StartingParametersTable.Data.amplitude;
            app.GelBoxApp.gel_data.par_est(app.n).skew_parameter = app.StartingParametersTable.Data.skew_parameter;
            app.GelBoxApp.gel_data.par_update(app.n) = 1; 

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FittingParametersUIFigure = uifigure('Visible', 'off');
            app.FittingParametersUIFigure.Name = 'Fitting Parameters';
            app.UpdateStartingParameterEstimatesButton = uibutton(app.FittingParametersUIFigure, 'push');
            app.UpdateStartingParameterEstimatesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateStartingParameterEstimatesButtonPushed, true);
            app.UpdateStartingParameterEstimatesButton.Text = 'Update Starting Parameter Estimates';
            app.StartingParametersTable = uitable(app.FittingParametersUIFigure);
            app.StartingParametersTable.ColumnName = {'Band No'; 'Peak Location'; 'Shape Parameter'; 'Amplitude'; 'Skewness Parameter'};
            app.StartingParametersTable.RowName = {};
            app.StartingParametersTable.ColumnEditable = [false true true true true];
            app.StartingParametersTable.CellEditCallback = createCallbackFcn(app, @StartingParametersTableCellEdit, true);
            app.StartingParameterEstimatesLabel = uilabel(app.FittingParametersUIFigure);
            app.StartingParameterEstimatesLabel.Text = 'Starting Parameter Estimates';
            app.CalculatedParametersLabel = uilabel(app.FittingParametersUIFigure);
            app.CalculatedParametersLabel.Enable = 'off';
            app.CalculatedParametersLabel.Text = 'Calculated Parameters';
            app.CalculatedParametersTable = uitable(app.FittingParametersUIFigure);
            app.CalculatedParametersTable.ColumnName = {'Band No'; 'Peak Location'; 'Shape Parameter'; 'Amplitude'; 'Skewness Parameter'};
            app.CalculatedParametersTable.RowName = {};
            app.CalculatedParametersTable.ColumnEditable = [false false false false false];
            app.CalculatedParametersTable.Enable = 'off';
            % Show the figure after all components are created
            app.FittingParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingOptionsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FittingParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FittingParametersUIFigure)
        end
    end
end