classdef GelBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GelBoxUIFigure                matlab.ui.Figure
        SelectedBoxFittingPanel       matlab.ui.container.Panel
        BandArea_1                    matlab.ui.control.NumericEditField
        BandAreaLabel_1               matlab.ui.control.Label
        rsquaredField                 matlab.ui.control.NumericEditField
        RsquaredLabel                 matlab.ui.control.Label
        BandArea_2                    matlab.ui.control.NumericEditField
        BandAreaLabel_2               matlab.ui.control.Label
        BandArea_3                    matlab.ui.control.NumericEditField
        BandAreaLabel_3               matlab.ui.control.Label
        BandRelativeAreaLabel_1       matlab.ui.control.Label
        RawOpticalDensityLabel        matlab.ui.control.Label
        BandRelativeArea_1            matlab.ui.control.NumericEditField
        BandRelativeAreaLabel_2       matlab.ui.control.Label
        BandRelativeArea_2            matlab.ui.control.NumericEditField
        BandRelativeAreaLabel_3       matlab.ui.control.Label
        BandRelativeArea_3            matlab.ui.control.NumericEditField
        DrawFittingCheckBox           matlab.ui.control.CheckBox
        BackgroundCorrectedOpticalDensityLabel  matlab.ui.control.Label
        raw_density_fit               matlab.ui.control.UIAxes
        background_corrected_raw_density_fit  matlab.ui.control.UIAxes
        SelectedBoxInformationPanel   matlab.ui.container.Panel
        SelectedBoxInformationArea    matlab.ui.control.TextArea
        GelImageFileInformationPanel  matlab.ui.container.Panel
        ImFInfoArea                   matlab.ui.control.TextArea
        GelImagePanel                 matlab.ui.container.Panel
        gel_image_axes                matlab.ui.control.UIAxes
        FileControlsPanel             matlab.ui.container.Panel
        OutputButton                  matlab.ui.control.Button
        SaveAnalysisButton            matlab.ui.control.Button
        LoadAnalysisButton            matlab.ui.control.Button
        InvertImageButton             matlab.ui.control.Button
        LoadImageButton               matlab.ui.control.Button
        AnalysisControlsPanel         matlab.ui.container.Panel
        BoxSelectionDropDown          matlab.ui.control.DropDown
        BoxSelectionDropDownLabel     matlab.ui.control.Label
        DeleteBoxButton               matlab.ui.control.Button
        NewBoxButton                  matlab.ui.control.Button
        NumberofBandsDropDown         matlab.ui.control.DropDown
        NumberofBandsDropDownLabel    matlab.ui.control.Label
        SelectedBoxOpticalDensitiesPanel  matlab.ui.container.Panel
        BoxZoomLabel                  matlab.ui.control.Label
        BackgroundCorrectedOpticalDensityLabel_2  matlab.ui.control.Label
        RawOpticalDensityLabel_2      matlab.ui.control.Label
        BackgroundAreaField           matlab.ui.control.NumericEditField
        BackgroundAreaLabel           matlab.ui.control.Label
        TotalAreaField                matlab.ui.control.NumericEditField
        TotalAreaEditFieldLabel       matlab.ui.control.Label
        box_inset                     matlab.ui.control.UIAxes
        background_corrected_raw_density  matlab.ui.control.UIAxes
        raw_density                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        gel_data % Description
    end
    
    methods (Access = public)

        function UpdateDisplay(app)
            %             temp_strings = get(gui.fitting_mode,'String');
            %             app.gel_data.fitting_mode = temp_strings{get(gui.fitting_mode,'Value')};
            if (isfield(app.gel_data,'box_handle'))
                n = numel(app.gel_data.box_handle);

                % Get selected box in control
                control_strings = app.BoxSelectionDropDown.Value;
                selected_box = str2num(control_strings);

                for i=1:n
                    p(i,1:4) = app.gel_data.box_handle(i).Position;
                end
                w = p(selected_box,3);
                h = p(selected_box,4);
                if ((w~=app.gel_data.old_width)|(h~=app.gel_data.old_height))
                    for i=1:n
                        p(i,3) = w;
                        p(i,4) = h;
                        app.gel_data.box_handle(i).Position = p(i,:);
                    end
                end

                % Store data in case we need to save it
                for i=1:n
                    app.gel_data.box_position(i,:) = app.gel_data.box_handle(i).Position;
                end

                % Store data for display
                d=[];
                for i=1:n
                    
                    d.box(i).fitting_mode = str2num(app.NumberofBandsDropDown.Value);
                    num_of_bands = d.box(i).fitting_mode
                    % Extract position
                    d.box(i).position = app.gel_data.box_handle(i).Position;

                    % Label it
                    set(app.gel_data.box_label(i),'String',sprintf('%.0f',i));
                    set(app.gel_data.box_label(i), ...
                        'Position',[d.box(i).position(1)+d.box(i).position(3) ...
                        d.box(i).position(2)-50]);

                    % Calculate profile
                    d.box(i).inset = imcrop(app.gel_data.im_data, ...
                        d.box(i).position);

                    m = imcomplement(d.box(i).inset);

                    x = flipud(mean(m,2));
                    y = 1:size(m,1);

                    x_back = linspace(x(1),x(end),numel(y));

                    switch num_of_bands
                        case 1
                            [x_bands,x_fit,r_squared] = ...
                            fit_1gaussian(y,x,x_back,num_of_bands);
                        case 2
                            [x_bands,x_fit,r_squared] = ...
                            Fit2Gaussian(app,y,x,x_back,num_of_bands);
                        case 3
                            [x_bands,x_fit,r_squared] = ...
                            fit_3gaussian(y,x,x_back,num_of_bands);
                    end

                    d.box(i).total_area = trapz(y,x);
                    d.box(i).background_area = trapz(y,x_back);

                    for j = 1 : num_of_bands
                    d.box(i).band_area(j) = trapz(y,x_bands(j,:));
                    end

                    % Store data for later

                    app.gel_data.box_data(i) = d.box(i);
                    app.gel_data.summary(i).x = x;
                    app.gel_data.summary(i).y = y;
                    app.gel_data.summary(i).x_fit = x_fit;
                    app.gel_data.summary(i).x_back = x_back;

                    if num_of_bands == 2
                        [~,peak_band_1] = max(x_bands(1,:));
                        [~,peak_band_2] = max(x_bands(2,:));

                        if peak_band_2 > peak_band_1

                            app.gel_data.summary(i).bottom = d.box(i).band_area(1);
                            app.gel_data.summary(i).top = d.box(i).band_area(2);
                            app.gel_data.summary(i).band_1 = x_bands(1,:);
                            app.gel_data.summary(i).band_2 = x_bands(2,:);
                        else
                            app.gel_data.summary(i).bottom = d.box(i).band_area(2);
                            app.gel_data.summary(i).top = d.box(i).band_area(1);
                            app.gel_data.summary(i).band_1 = x_bands(2,:);
                            app.gel_data.summary(i).band_2 = x_bands(1,:);
                        end
                    elseif num_of_bands == 3
                        [~,peak_band_1] = max(x_bands(1,:));
                        [~,peak_band_2] = max(x_bands(2,:));
                        [~,peak_band_3] = max(x_bands(3,:));

                        peak_band = [peak_band_1 peak_band_2 peak_band_3];

                        %sort
                        [~,sort_ix] = sort(peak_band);
                        app.gel_data.summary(i).bottom = d.box(i).band_area( ...
                            sort_ix(1));
                        app.gel_data.summary(i).middle = d.box(i).band_area( ...
                            sort_ix(2));
                        app.gel_data.summary(i).top = d.box(i).band_area( ...
                            sort_ix(3));
                        app.gel_data.summary(i).band_1 = x_bands( ...
                            sort_ix(1),:);
                        app.gel_data.summary(i).band_2 = x_bands( ...
                            sort_ix(2),:);
                        app.gel_data.summary(i).band_3 = x_bands( ...
                            sort_ix(3),:);
                    else
                        app.gel_data.summary(i).band_1 = x_bands(1,:);
                    end

                    app.gel_data.summary(i).inset = d.box(i).inset;
                    app.gel_data.summary(i).r_squared = r_squared;

                    % Display
                    if (i==selected_box)
                       app.TotalAreaField.Value = d.box(i).total_area;
                       app.BackgroundAreaField.Value = d.box(i).background_area;

                       app.rsquaredField.Value = r_squared; 
                        center_image_with_preserved_aspect_ratio( ...
                            d.box(i).inset, ...
                            app.box_inset);
                       cla(app.raw_density)
                       plot(app.raw_density,x,y,"Color",'k',"LineWidth",2)
                       hold(app.raw_density,"on")
                       plot(app.raw_density,x_back,y,':g',"LineWidth",2)
                       x_t_end = ceil(max(x)/1000)*1000;
                       x_t_mid = round(x_t_end/2);
                       x_ticks = [0 x_t_mid x_t_end];
                       xticks(app.raw_density,x_ticks)
                       xlim(app.raw_density,[0 x_t_end])
                       ylim(app.raw_density,[1 max(y)]);

                       xticks(app.raw_density_fit,x_ticks)
                       xlim(app.raw_density_fit,[0 x_t_end])
                       ylim(app.raw_density_fit,[1 max(y)]);


                       plot(app.background_corrected_raw_density, ...
                           x-x_back',y,"Color",'k',"LineWidth",2)
                       ylim(app.background_corrected_raw_density, ...
                           [1 max(y)]);

                       x_t_end = ceil(max(x-x_back')/500)*500;
                       if min(x-x_back') < 0
                           x_tick_rounder = -500;
                       else
                           x_tick_rounder = 500;
                       end
                       x_t_beginning = ceil(min(x-x_back')/x_tick_rounder)*x_tick_rounder;
                       x_t_mid = round(x_t_end/2);
                       x_ticks = [x_t_beginning x_t_mid x_t_end];
                       xticks(app.background_corrected_raw_density,x_ticks)
                       xlim(app.background_corrected_raw_density,[x_t_beginning x_t_end])
                       ylim(app.background_corrected_raw_density,[1 max(y)]);

                       xticks(app.background_corrected_raw_density_fit,x_ticks)
                       xlim(app.background_corrected_raw_density_fit,[x_t_beginning x_t_end])
                       ylim(app.background_corrected_raw_density_fit,[1 max(y)]);

                       switch num_of_bands
                           case 1
                               color = {'r'};
                               app.BandArea_1.Value = ...
                               app.gel_data.summary(i).band_1;
                           case 2
                               color = {'r','b'};
                               total_band = app.gel_data.summary(i).bottom ...
                                   + app.gel_data.summary(i).top;
                               app.BandArea_1.Value = ...
                               app.gel_data.summary(i).bottom;

                               app.BandRelativeArea_1.Value = ...
                               app.gel_data.summary(i).bottom/total_band;

                               app.BandArea_2.Value = ...
                               app.gel_data.summary(i).top;

                               app.BandRelativeArea_2.Value = ...
                               app.gel_data.summary(i).top/total_band;
                           case 3
                               color = {'r','b','g'};
                       end
                        
                       
                       cla(app.raw_density_fit)
                       cla(app.background_corrected_raw_density_fit)
                       plot(app.raw_density_fit, ...
                           x,y,"Color",'k',"LineWidth",2)
                       plot(app.background_corrected_raw_density_fit, ...
                           x-x_back',y,"Color",'k',"LineWidth",2)
                       for j = 1 : num_of_bands
                            patch(app.raw_density_fit, ...
                                x_back+x_bands(j,:), ...
                                y,color{j},'FaceAlpha',0.25, ...
                                'EdgeColor',color{j},'EdgeAlpha',0.25, ...
                                'LineWidth',2)
                            patch(app.background_corrected_raw_density_fit, ...
                                x_bands(j,:), ...
                                y,color{j},'FaceAlpha',0.25, ...
                                'EdgeColor',color{j},'EdgeAlpha',0.25, ...
                                'LineWidth',2)
                       end
                       ylim(app.raw_density_fit, ...
                           [1 max(y)]);
                       ylim(app.background_corrected_raw_density_fit, ...
                           [1 max(y)]);
                            
                       app.SelectedBoxInformationArea.Value = printstruct(d.box(i));

                    end
                end
            end
        end
        
       function [y_bands, y_fit,r_squared] = FitGaussian(x,y,y_back, ...
                no_of_bands)
            
            
        end
        
        function [y_bands, y_fit,r_squared] = Fit2Gaussian(app,x,y,...
                y_back,no_of_bands)
            peaks=find_peaks('x',x, ...
                             'y',y, ...
                             'min_rel_delta_y',0.05, ...
                             'min_x_index_spacing',2);
            
            if numel(peaks.max_indices) == no_of_bands
                first_curve_x_estimate=peaks.max_indices(1);
                second_curve_x_estimate=peaks.max_indices(2);
            else
                first_curve_x_estimate = 0.3*length(x);
                second_curve_x_estimate = 0.6*length(x);
            end

            target = y';

            target = target - y_back;
            [max_value,~]=max(target);

            half_distance=(0.1*length(x));
            alfa_estimate = -log(0.5)/(half_distance^2);

            first_curve_shape_estimate = alfa_estimate;
            first_curve_amp_estimate = max_value;
            first_curve_skew_estimate = 1;
    
            second_curve_amp_estimate = first_curve_amp_estimate;
            second_curve_shape_estimate = alfa_estimate;

            par = [first_curve_x_estimate ...
                   first_curve_shape_estimate ...
                   first_curve_amp_estimate ...
                   first_curve_skew_estimate ...
                   second_curve_x_estimate ...
                   second_curve_amp_estimate ...
                   second_curve_shape_estimate ...
                   ];

            j = 1;
            e = [];

            opts=optimset('fminsearch');
            opts.Display='off';
            opts.MaxIter=1000;
            opts.MaxFunEvals=10000;

            [p_result,fval,exitflag,output] = fminsearch(@profile_error_2gaussian, par, opts);

            r_squared = calculate_r_squared(y',y_fit+y_back);

            function trial_e = profile_error_2gaussian(par)

                [y_bands,y_fit] = calculate_profile(x,par);

                e(j) = 0;

                for i  = 1 : numel(target)
                    e(j) = e(j) + (y_fit(i) - target(i))^2;
                end

                trial_e = e(end);

                if any(par<0)
                    trial_e = 10^6;
                end
                r_squared_iter = calculate_r_squared(target,y_fit);
                j = j + 1;
                if app.DrawFittingCheckBox.Value
                    cla(app.background_corrected_raw_density_fit)
                    plot(app.background_corrected_raw_density_fit,y_fit,x,'LineWidth',2)
                    hold(app.background_corrected_raw_density_fit, 'on')
                    plot(app.background_corrected_raw_density_fit,target,x,'LineWidth',2,'LineStyle','-.','Color','k')
                    app.rsquaredField.Value = r_squared_iter;
                    drawnow
%                     area_1 = trapz(x,y_bands(1,:));
%                     area_2 = trapz(x,y_bands(2,:));
%                     plot(y_bands(1,:),x,'ro');
%                     plot(y_bands(2,:),x,'bo');
%                     str1 = sprintf('Red: %.3f\n Blue: %.3f', area_1, area_2);

%                     xL=xlim;
%                     yL=ylim;
%                     text(0.99*xL(2),0.99*yL(2),str1,'HorizontalAlignment','right','VerticalAlignment','top')
                end
            end
            function [y_bands,y_fit] = calculate_profile(x,par)
                
                x1 = par(1);
                curve_shape1 = par(2);
                amp1 = par(3);
                skew1 = par(4);
                x2 = par(5);
                amp2 = par(6);
                curve_shape2 = par(7);
                
                %         x1
                y_first = skewed_Gaussian(x,x1,curve_shape1,amp1,skew1);
                y_second = skewed_Gaussian(x,x2,curve_shape2,amp2,skew1);

                y_fit = y_first + y_second;
                y_bands(1,:) = y_first;
                y_bands(2,:) = y_second;

            end
            function y=skewed_Gaussian(x,x0,gamma,A,skew1)
                offset = zeros(1,length(x));
                offset((x-x0)>0) = skew1*(x((x-x0)>0)-x0);
                y=  A*exp(-gamma*(((x-x0)+offset).^2));
            end
        end

        function [y_bands, y_fit,r_squared] = Fit3Gaussian(x,y,y_back, ...
                no_of_bands)

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            addpath(genpath('../code/utilities'));
            writelines(evalc('type(mfilename(''fullpath'')+".mlapp")'),mfilename('fullpath')+".m");
%             colormap(app.gel_image_axes,'gray')
            colormap(app.GelBoxUIFigure, 'gray');

        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file_string,path_string]=uigetfile2( ...
                {'*.png','PNG';'*.tif','TIF'}, ...
                'Select image file');
            app.SaveAnalysisButton.Enable = 1;
            app.OutputButton.Enable = 1;
            if (path_string~=0)

                app.gel_data = [];

                app.gel_data.invert_status = 0;
                app.gel_data.image_file_string = fullfile(path_string,file_string);
                app.gel_data.im_data = imread(app.gel_data.image_file_string);
                if (ndims(app.gel_data.im_data)==3)
                    app.gel_data.im_data = rgb2gray(app.gel_data.im_data);
                end
                
                center_image_with_preserved_aspect_ratio( ...
                app.gel_data.im_data, ...
                app.gel_image_axes);

                app.gel_data.imfinfo = imfinfo(app.gel_data.image_file_string);
                app.ImFInfoArea.Value = printstruct(app.gel_data.imfinfo);

            end

        end

        % Button pushed function: InvertImageButton
        function InvertImageButtonPushed(app, event)
            app.gel_data.im_data = imcomplement(app.gel_data.im_data);
            center_image_with_preserved_aspect_ratio( ...
                app.gel_data.im_data, ...
                app.gel_image_axes);
            app.gel_data.invert_status = 1;
        end

        % Button pushed function: LoadAnalysisButton
        function LoadAnalysisButtonPushed(app, event)
            % Delete any old boxes
            if (isfield(app.gel_data,'box_handle'))
                n = numel(app.gel_data.box_handle);
                for i=1:n
                    delete(app.gel_data.box_handle(i));
                end
            end
            [file_string,path_string] = uigetfile2( ...
            {'*.gdf','Gel data file'},'Select file to load prior analysis');
            
            if (path_string~=0)

            temp = load(fullfile(path_string,file_string),'-mat','save_data');
            save_data = temp.save_data;

            % Restore
            app.gel_data = [];
            app.gel_data.image_file_string = save_data.image_file_string;
            app.gel_data.im_data = save_data.im_data;
            app.gel_data.imfinfo = save_data.imfinfo;
            
            center_image_with_preserved_aspect_ratio( ...
                app.gel_data.im_data, ...
                app.gel_image_axes);
            end
            error('utku')
        end

        % Button pushed function: NewBoxButton
        function NewBoxButtonPushed(app, event)
            if (~isfield(app.gel_data,'box_handle'))
                app.DeleteBoxButton.Enable = 1;
                n=1;
                app.gel_data.box_handle(n) = drawrectangle(app.gel_image_axes);
                p = app.gel_data.box_handle(n).Position;
                app.gel_data.old_width = p(3);
                app.gel_data.old_height = p(4);
            else
                n = 1 + numel(app.gel_data.box_handle);
                p = app.gel_data.box_handle(n-1).Position;

                app.gel_data.box_handle(n) = images.roi.Rectangle(app.gel_image_axes, ...
                    'Position',p + [20,0,0,0]);
                for i=1:(n-1)
                    app.gel_data.box_handle(i).InteractionsAllowed = 'none';
                end
            end

            addlistener(app.gel_data.box_handle(n),"MovingROI", ...
                @(src,evt) new_box_position(evt));

            % Set color to last box
            app.gel_data.box_handle(n).Color = [0 1 0];
            app.gel_data.box_handle(n).FaceAlpha = 0;
            for i=1:(n-1)
                app.gel_data.box_handle(i).Color = [1 0 0];
            end

            % Add in a label
            p = app.gel_data.box_handle(n).Position;
            app.gel_data.box_label(n) = text(app.gel_image_axes, ...
                p(1)+p(3),p(2)-50,sprintf('%.0f',n));

            % Update zoom control
            for i=1:n
                control_strings{i}=sprintf('%.0f',i);
            end

            app.BoxSelectionDropDown.Items = control_strings;
            app.BoxSelectionDropDown.Value = control_strings{n};

            UpdateDisplay(app);

            function new_box_position(evt);
                if (isfield(app.gel_data,'box_position'))
                    box_position = app.gel_data.box_position;
                    [r,c]=size(box_position);
                    if (r>=n)&(~isequal(box_position(n,:),evt.CurrentPosition))
                        UpdateDisplay(app);
                    end
                else
                        UpdateDisplay(app);
                end
            end

        end

        % Value changed function: NumberofBandsDropDown
        function NumberofBandsDropDownValueChanged(app, event)
            value = app.NumberofBandsDropDown.Value;
            
            switch value
                case '1'
                    try
                    app.BandArea_2.Value = 0;
                    app.BandArea_2.Enable = 0;
                    app.BandRelativeArea_2.Value = 0;
                    app.BandRelativeArea_2.Enable = 0;
                    app.BandAreaLabel_2.Enable = 0;
                    app.BandRelativeAreaLabel_2.Enable = 0;
                    app.BandArea_3.Value = 0;
                    app.BandArea_3.Enable = 0;
                    app.BandRelativeArea_3.Value = 0;
                    app.BandRelativeArea_3.Enable = 0;
                    app.BandAreaLabel_3.Enable = 0;
                    app.BandRelativeAreaLabel_3.Enable = 0;
                    end
                case '2'
                    app.BandArea_2.Enable = 1;
                    app.BandRelativeArea_2.Enable = 1;
                    app.BandAreaLabel_2.Enable = 1;
                    app.BandRelativeAreaLabel_2.Enable = 1;
                    try
                    app.BandArea_3.Value = 0;
                    app.BandArea_3.Enable = 0;
                    app.BandRelativeArea_3.Enable = 0;
                    app.BandRelativeArea_3.Value = 0;
                    app.BandAreaLabel_3.Enable = 0;
                    app.BandRelativeAreaLabel_3.Enable = 0;
                    end

                case '3'
                    app.BandArea_2.Enable = 1;
                    app.BandRelativeArea_2.Enable = 1;
                    app.BandAreaLabel_2.Enable = 1;
                    app.BandRelativeAreaLabel_2.Enable = 1;
                    app.BandArea_3.Enable = 1;
                    app.BandRelativeArea_3.Enable = 1;
                    app.BandAreaLabel_3.Enable = 1;
                    app.BandRelativeAreaLabel_3.Enable = 1;
            end
            UpdateDisplay(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GelBoxUIFigure = uifigure('Visible', 'off');
            colormap(app.GelBoxUIFigure, 'parula');
            app.GelBoxUIFigure.Name = 'GelBox';
            app.SelectedBoxOpticalDensitiesPanel = uipanel(app.GelBoxUIFigure);
            app.SelectedBoxOpticalDensitiesPanel.Title = 'Selected Box Optical Densities';
            app.raw_density = uiaxes(app.SelectedBoxOpticalDensitiesPanel);
            xlabel(app.raw_density, 'Optical Density')
            ylabel(app.raw_density, 'Pixel')
            app.background_corrected_raw_density = uiaxes(app.SelectedBoxOpticalDensitiesPanel);
            xlabel(app.background_corrected_raw_density, 'Optical Density')
            ylabel(app.background_corrected_raw_density, 'Pixel')
            app.box_inset = uiaxes(app.SelectedBoxOpticalDensitiesPanel);
            xlabel(app.box_inset, 'Optical Density')
            ylabel(app.box_inset, 'Pixel')
            app.TotalAreaEditFieldLabel = uilabel(app.SelectedBoxOpticalDensitiesPanel);
            app.TotalAreaEditFieldLabel.Text = 'Total Area';
            app.TotalAreaField = uieditfield(app.SelectedBoxOpticalDensitiesPanel, 'numeric');
            app.TotalAreaField.ValueDisplayFormat = '%.2f';
            app.TotalAreaField.Editable = 'off';
            app.BackgroundAreaLabel = uilabel(app.SelectedBoxOpticalDensitiesPanel);
            app.BackgroundAreaLabel.Text = 'Background Area';
            app.BackgroundAreaField = uieditfield(app.SelectedBoxOpticalDensitiesPanel, 'numeric');
            app.BackgroundAreaField.ValueDisplayFormat = '%.2f';
            app.BackgroundAreaField.Editable = 'off';
            app.RawOpticalDensityLabel_2 = uilabel(app.SelectedBoxOpticalDensitiesPanel);
            app.RawOpticalDensityLabel_2.Text = 'Raw Optical Density';
            app.BackgroundCorrectedOpticalDensityLabel_2 = uilabel(app.SelectedBoxOpticalDensitiesPanel);
            app.BackgroundCorrectedOpticalDensityLabel_2.Text = 'Background Corrected Optical Density';
            app.BoxZoomLabel = uilabel(app.SelectedBoxOpticalDensitiesPanel);
            app.BoxZoomLabel.Text = 'Box Zoom';
            app.AnalysisControlsPanel = uipanel(app.GelBoxUIFigure);
            app.AnalysisControlsPanel.Title = 'Analysis Controls';
            app.NumberofBandsDropDownLabel = uilabel(app.AnalysisControlsPanel);
            app.NumberofBandsDropDownLabel.Text = 'Number of Bands';
            app.NumberofBandsDropDown = uidropdown(app.AnalysisControlsPanel);
            app.NumberofBandsDropDown.Items = {'1', '2', '3'};
            app.NumberofBandsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofBandsDropDownValueChanged, true);
            app.NumberofBandsDropDown.Value = '1';
            app.NewBoxButton = uibutton(app.AnalysisControlsPanel, 'push');
            app.NewBoxButton.ButtonPushedFcn = createCallbackFcn(app, @NewBoxButtonPushed, true);
            app.NewBoxButton.Text = 'New Box';
            app.DeleteBoxButton = uibutton(app.AnalysisControlsPanel, 'push');
            app.DeleteBoxButton.Enable = 'off';
            app.DeleteBoxButton.Text = 'Delete Box';
            app.BoxSelectionDropDownLabel = uilabel(app.AnalysisControlsPanel);
            app.BoxSelectionDropDownLabel.Text = 'Box Selection';
            app.BoxSelectionDropDown = uidropdown(app.AnalysisControlsPanel);
            app.BoxSelectionDropDown.Items = {};
            app.BoxSelectionDropDown.Placeholder = 'No data';
            app.BoxSelectionDropDown.Value = {};
            app.FileControlsPanel = uipanel(app.GelBoxUIFigure);
            app.FileControlsPanel.Title = 'File Controls';
            app.LoadImageButton = uibutton(app.FileControlsPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.InvertImageButton = uibutton(app.FileControlsPanel, 'push');
            app.InvertImageButton.ButtonPushedFcn = createCallbackFcn(app, @InvertImageButtonPushed, true);
            app.InvertImageButton.Text = 'Invert Image';
            app.LoadAnalysisButton = uibutton(app.FileControlsPanel, 'push');
            app.LoadAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnalysisButtonPushed, true);
            app.LoadAnalysisButton.Text = 'Load Analysis';
            app.SaveAnalysisButton = uibutton(app.FileControlsPanel, 'push');
            app.SaveAnalysisButton.Enable = 'off';
            app.SaveAnalysisButton.Text = 'Save Analysis';
            app.OutputButton = uibutton(app.FileControlsPanel, 'push');
            app.OutputButton.Enable = 'off';
            app.OutputButton.Text = 'Output';
            app.GelImagePanel = uipanel(app.GelBoxUIFigure);
            app.GelImagePanel.Title = 'Gel Image';
            app.gel_image_axes = uiaxes(app.GelImagePanel);
            app.gel_image_axes.XTick = [];
            app.gel_image_axes.YTick = [];
            app.gel_image_axes.Visible = 'off';
            app.GelImageFileInformationPanel = uipanel(app.GelBoxUIFigure);
            app.GelImageFileInformationPanel.Title = 'Gel Image File Information';
            app.ImFInfoArea = uitextarea(app.GelImageFileInformationPanel);
            app.ImFInfoArea.Editable = 'off';
            app.SelectedBoxInformationPanel = uipanel(app.GelBoxUIFigure);
            app.SelectedBoxInformationPanel.Title = 'Selected Box Information';
            app.SelectedBoxInformationArea = uitextarea(app.SelectedBoxInformationPanel);
            app.SelectedBoxInformationArea.Editable = 'off';
            app.SelectedBoxFittingPanel = uipanel(app.GelBoxUIFigure);
            app.SelectedBoxFittingPanel.Title = 'Selected Box Fitting';
            app.background_corrected_raw_density_fit = uiaxes(app.SelectedBoxFittingPanel);
            xlabel(app.background_corrected_raw_density_fit, 'Optical Density')
            ylabel(app.background_corrected_raw_density_fit, 'Pixel')
            app.raw_density_fit = uiaxes(app.SelectedBoxFittingPanel);
            xlabel(app.raw_density_fit, 'Optical Density')
            ylabel(app.raw_density_fit, 'Pixel')
            app.BackgroundCorrectedOpticalDensityLabel = uilabel(app.SelectedBoxFittingPanel);
            app.BackgroundCorrectedOpticalDensityLabel.Text = 'Background Corrected Optical Density';
            app.DrawFittingCheckBox = uicheckbox(app.SelectedBoxFittingPanel);
            app.DrawFittingCheckBox.Text = 'Draw Fitting';
            app.BandRelativeArea_3 = uieditfield(app.SelectedBoxFittingPanel, 'numeric');
            app.BandRelativeArea_3.ValueDisplayFormat = '%.2f';
            app.BandRelativeArea_3.Editable = 'off';
            app.BandRelativeArea_3.Enable = 'off';
            app.BandRelativeAreaLabel_3 = uilabel(app.SelectedBoxFittingPanel);
            app.BandRelativeAreaLabel_3.Enable = 'off';
            app.BandRelativeAreaLabel_3.Text = 'Band Relative Area 3 (Green)';
            app.BandRelativeArea_2 = uieditfield(app.SelectedBoxFittingPanel, 'numeric');
            app.BandRelativeArea_2.ValueDisplayFormat = '%.2f';
            app.BandRelativeArea_2.Editable = 'off';
            app.BandRelativeArea_2.Enable = 'off';
            app.BandRelativeAreaLabel_2 = uilabel(app.SelectedBoxFittingPanel);
            app.BandRelativeAreaLabel_2.Enable = 'off';
            app.BandRelativeAreaLabel_2.Text = 'Band Relative Area 2 (Blue)';
            app.BandRelativeArea_1 = uieditfield(app.SelectedBoxFittingPanel, 'numeric');
            app.BandRelativeArea_1.ValueDisplayFormat = '%.2f';
            app.BandRelativeArea_1.Editable = 'off';
            app.RawOpticalDensityLabel = uilabel(app.SelectedBoxFittingPanel);
            app.RawOpticalDensityLabel.Text = 'Raw Optical Density';
            app.BandRelativeAreaLabel_1 = uilabel(app.SelectedBoxFittingPanel);
            app.BandRelativeAreaLabel_1.Text = 'Band Relative Area 1 (Red)';
            app.BandAreaLabel_3 = uilabel(app.SelectedBoxFittingPanel);
            app.BandAreaLabel_3.Enable = 'off';
            app.BandAreaLabel_3.Text = 'Band Area 3 (Green)';
            app.BandArea_3 = uieditfield(app.SelectedBoxFittingPanel, 'numeric');
            app.BandArea_3.ValueDisplayFormat = '%.2f';
            app.BandArea_3.Editable = 'off';
            app.BandArea_3.Enable = 'off';
            app.BandAreaLabel_2 = uilabel(app.SelectedBoxFittingPanel);
            app.BandAreaLabel_2.Enable = 'off';
            app.BandAreaLabel_2.Text = 'Band Area 2 (Blue)';
            app.BandArea_2 = uieditfield(app.SelectedBoxFittingPanel, 'numeric');
            app.BandArea_2.ValueDisplayFormat = '%.2f';
            app.BandArea_2.Editable = 'off';
            app.BandArea_2.Enable = 'off';
            app.RsquaredLabel = uilabel(app.SelectedBoxFittingPanel);
            app.RsquaredLabel.Text = 'R - squared';
            app.rsquaredField = uieditfield(app.SelectedBoxFittingPanel, 'numeric');
            app.rsquaredField.ValueDisplayFormat = '%.2f';
            app.rsquaredField.Editable = 'off';
            app.BandAreaLabel_1 = uilabel(app.SelectedBoxFittingPanel);
            app.BandAreaLabel_1.Text = 'Band Area 1 (Red)';
            app.BandArea_1 = uieditfield(app.SelectedBoxFittingPanel, 'numeric');
            app.BandArea_1.ValueDisplayFormat = '%.2f';
            app.BandArea_1.Editable = 'off';
            % Show the figure after all components are created
            app.GelBoxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GelBox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GelBoxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GelBoxUIFigure)
        end
    end
end