classdef SummaryPlotWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FittingOptionsUIFigure        matlab.ui.Figure
        FigureFileNameField           matlab.ui.control.EditField
        FigureFileNameEditFieldLabel  matlab.ui.control.Label
        GenerateSummaryPlotsButton    matlab.ui.control.Button
        OutputPathField               matlab.ui.control.EditField
        SelectOutputFolderButton      matlab.ui.control.Button
        RSquaredPlotCheckBox          matlab.ui.control.CheckBox
        ThirdBand                     matlab.ui.control.EditField
        ThirdBandLabel                matlab.ui.control.Label
        SecondBand                    matlab.ui.control.EditField
        SecondBandLabel               matlab.ui.control.Label
        FirstBand                     matlab.ui.control.EditField
        FirstBandLabel                matlab.ui.control.Label
        NumberofbandsDropDown         matlab.ui.control.DropDown
        NumberofbandsDropDownLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        GelBoxApp % Description
    end
    
    properties (Access = public)
        output_path % Description
        box_layout_fname
        r_squared_fname
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.GelBoxApp = caller;


        end

        % Button pushed function: SelectOutputFolderButton
        function SelectOutputFolderButtonPushed(app, event)
            app.output_path = uigetdir2;
            app.OutputPathField.Value = app.output_path;
        end

        % Value changed function: FigureFileNameField
        function FigureFileNameFieldValueChanged(app, event)
            fname = app.FigureFileNameField.Value;
            app.box_layout_fname = sprintf('%s\\%s_box_layout', ...
                app.output_path,fname);
            app.r_squared_fname = sprintf('%s\\%s_r_squared', ...
                app.output_path,fname);
            app.NumberofbandsDropDownLabel.Enable = 1;
            app.NumberofbandsDropDown.Enable = 1;
            app.FirstBandLabel.Enable = 1;
            app.FirstBand.Enable = 1;
            app.RSquaredPlotCheckBox.Enable = 1;


        end

        % Value changed function: NumberofbandsDropDown
        function NumberofbandsDropDownValueChanged(app, event)
            value = app.NumberofbandsDropDown.Value;
            switch value
                case "1"
                    app.SecondBand.Enable = 0;
                    app.SecondBandLabel.Enable = 0;
                    app.ThirdBand.Enable = 0;
                    app.ThirdBandLabel.Enable = 0;
                case "2"
                    app.SecondBand.Enable = 1;
                    app.SecondBandLabel.Enable = 1;
                    app.ThirdBand.Enable = 0;
                    app.ThirdBandLabel.Enable = 0;
                case "3"
                    app.SecondBand.Enable = 1;
                    app.SecondBandLabel.Enable = 1;
                    app.ThirdBand.Enable = 1;
                    app.ThirdBandLabel.Enable = 1;
            end
        end

        % Button pushed function: GenerateSummaryPlotsButton
        function GenerateSummaryPlotsButtonPushed(app, event)
            %summary structure
            summary = app.GelBoxApp.gel_data.summary;  
            [m,n] = size(summary);
            
            total_figures = n*3;
            fig_handles = 1:total_figures;
            im_handles = 1:3:total_figures;
            back_fit_handles = 2:3:total_figures;
            fit_handles = 3:3:total_figures;


            if n == 1
                no_of_panels_wide = 3;
            else
                no_of_panels_wide = 6;
            end

            no_of_panels_high = (total_figures + ...
            mod(total_figures,no_of_panels_wide))/no_of_panels_wide;

            left_pads = 0.05 * ones(1,no_of_panels_wide);
            right_pads = 0.05 * ones(1,no_of_panels_wide);
            if no_of_panels_wide == 3
                right_pads(no_of_panels_wide) = 0.5;
            else
                right_pads([2,5]) = 0.25;
                right_pads(no_of_panels_wide) = 0.5;

            end
            
            if mod(total_figures,no_of_panels_wide) ~= 0
                res_total_figures = no_of_panels_high * no_of_panels_wide;
                res_total_figures = 1 : res_total_figures;
                omit_panels = [res_total_figures(end-2) ... 
                               res_total_figures(end-1) ...
                               res_total_figures(end)];
            else
                omit_panels = [];
            end

            sp = initialise_publication_quality_figure( ...
                'no_of_panels_wide', no_of_panels_wide, ...
                'no_of_panels_high', no_of_panels_high, ...
                'top_margin', 0, ...
                'bottom_margin', 0, ...
                'right_margin', 0.5, ...
                'individual_padding', 1, ...
                'left_pads', repmat(left_pads,[1 no_of_panels_high]), ...
                'right_pads', repmat(right_pads,[1 no_of_panels_high]), ...
                'axes_padding_top', 0.4, ...
                'axes_padding_bottom',0.4, ...
                'panel_label_font_size', 0, ...
                'omit_panels',omit_panels,...
                'figure_handle',1);

            colors = {'r','b','g'};
            labels = {app.FirstBand.Value, ...
                      app.SecondBand.Value, ...
                      app.ThirdBand.Value};

            for i = 1 : length (im_handles)
                h = subplot(sp(im_handles(i)));
                colormap(h,"gray");
                t1 = sprintf('Box %i',i);
                title(t1, 'FontSize',7);
                center_image_with_preserved_aspect_ratio( ...
                summary(i).inset,sp(im_handles(i)));

                subplot(sp(back_fit_handles(i)))
                plot(summary(i).x,summary(i).y,'k', 'LineWidth',1.5);

                hold on
             
                subplot(sp(fit_handles(i)))
                plot(summary(i).x-(summary(i).x_back)', ...
                    summary(i).y,'-.k', 'LineWidth',1.5);
                hold on

                switch app.NumberofbandsDropDown.Value
                    case "1"
                        subplot(sp(fit_handles(i)))
                        bottom = patch(summary(i).band_1, ...
                            summary(i).y,colors{1},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{1},'EdgeAlpha',0.05);
                        tot_area = summary(i).bottom;
                        bottom_area = (summary(i).bottom)/tot_area;

                        subplot(sp(back_fit_handles(i)))
                        hold on
                        patch(summary(i).x_back+summary(i).band_1, ...
                            summary(i).y,colors{1},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{1},'EdgeAlpha',0.05);

                        str_bottom = sprintf('%s = %.2f', ...
                            string(labels{1}),bottom_area);

                        leg_labels = {str_bottom};
                        figs = [bottom];

                        legendflex([bottom], leg_labels, ...
                            'xscale',0.15, ...
                            'anchor',{'ne','ne'}, ...
                            'buffer',[20 0], ...
                            'padding',[1 1 2], ...
                            'FontSize',6, ...
                            'text_y_padding', -2);

                    case "2"
                        subplot(sp(fit_handles(i)))
                        % xlim([0 20000])
                        bottom = patch(summary(i).band_1, ...
                            summary(i).y,colors{1},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{1},'EdgeAlpha',0.05);
                        top = patch(summary(i).band_2, ...
                            summary(i).y,colors{2},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{2},'EdgeAlpha',0.05);
                        tot_area = summary(i).bottom + summary(i).top;
                        bottom_area = (summary(i).bottom)/tot_area;
                        top_area = (summary(i).top)/tot_area;

                        subplot(sp(back_fit_handles(i)))
                        hold on
                        patch(summary(i).x_back+summary(i).band_1, ...
                            summary(i).y,colors{1},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{1},'EdgeAlpha',0.05);
                        patch(summary(i).x_back+summary(i).band_2, ...
                            summary(i).y,colors{2},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{2},'EdgeAlpha',0.05);
                        str_bottom = sprintf('%s = %.2f', ...
                            string(labels{1}),bottom_area);
                        str_top = sprintf('%s = %.2f', ...
                            string(labels{2}),top_area);

                        leg_labels = {str_bottom,str_top};
                        figs = [bottom top];

                    case "3"
                        subplot(sp(fit_handles(i)))
                        bottom = patch(summary(i).band_1, ...
                            summary(i).y,colors{1},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{1},'EdgeAlpha',0.05);
                        middle = patch(summary(i).band_2, ...
                            summary(i).y,colors{2},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{2},'EdgeAlpha',0.05);
                        top = patch(summary(i).band_3, ...
                            summary(i).y,colors{3},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{3},'EdgeAlpha',0.05);
                        tot_area = summary(i).bottom + summary(i).middle + summary(i).top;
                        bottom_area = (summary(i).bottom)/tot_area;
                        middle_area = (summary(i).middle)/tot_area;
                        top_area = (summary(i).top)/tot_area;

                        subplot(sp(back_fit_handles(i)))
                        hold on
                        patch(summary(i).x_back+summary(i).band_1, ...
                            summary(i).y,colors{1},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{1},'EdgeAlpha',0.05);
                        patch(summary(i).x_back+summary(i).band_2, ...
                            summary(i).y,colors{2},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{2},'EdgeAlpha',0.05);
                        patch(summary(i).x_back+summary(i).band_3, ...
                            summary(i).y,colors{3},'FaceAlpha',0.25, ...
                            'EdgeColor',colors{3},'EdgeAlpha');

                        str_bottom = sprintf('%s = %.2f', ...
                            string(labels{1}),bottom_area);
                        str_middle = sprintf('%s = %.2f', ...
                            string(labels{2}),middle_area);
                        str_top = sprintf('%s = %.2f', ...
                            string(labels{3}),top_area);

                        leg_labels = {str_bottom,str_top,str_middle};
                        figs = [bottom middle top];

                        legendflex([bottom middle top], leg_labels, ...
                            'xscale',0.15, ...
                            'anchor',{'ne','ne'}, ...
                            'buffer',[20 0], ...
                            'padding',[1 1 2], ...
                            'FontSize',6, ...
                            'text_y_padding', -2);
                end


                f_leg = sprintf('R^2 = %.3f',summary(i).r_squared);
                f_color = '#1aff00';

                subplot(sp(back_fit_handles(i)))
                hold on
                plot(summary(i).x_back,summary(i).y, ...
                    '-.m','LineWidth',1.5);
                plot(summary(i).x_back + summary(i).x_fit, ...
                    summary(i).y,':',"LineWidth",1.5,'Color',f_color)

                subplot(sp(fit_handles(i)))
                hold on
                plot(zeros(1,numel(summary(i).y)),summary(i).y, ...
                    '-.m','LineWidth',1.5);
                f = plot(summary(i).x_fit, ...
                    summary(i).y,':',"LineWidth",1.5,'Color',f_color);
                leg_labels{end+1} = f_leg;
                figs(end+1) = f;
                
                legendflex(figs, leg_labels, ...
                    'xscale',0.15, ...
                    'anchor',{'ne','ne'}, ...
                    'buffer',[20 0], ...
                    'padding',[1 1 2], ...
                    'FontSize',6, ...
                    'text_y_padding', 0);


            end

            for i = 1:length(fit_handles)

                x_pow = ceil(log10(max(summary(i).x-summary(i).x_back')));
                x_tick_rounder = 10^(x_pow - 1);
                x_t_end = ceil(max(summary(i).x-summary(i).x_back') ...
                    /x_tick_rounder)*x_tick_rounder;
                if min(summary(i).x-summary(i).x_back') < 0
                    x_tick_rounder = -10^(x_pow - 2)*0.25;
                else
                    x_tick_rounder = 10^(x_pow - 2)*0.25;
                end
                x_t_beginning = ceil(min(summary(i).x-summary(i).x_back')/x_tick_rounder)*x_tick_rounder;
                x_t_mid = round(x_t_end/2);
                x_ticks = [x_t_beginning x_t_mid x_t_end];


                improve_axes('axis_handle',sp(fit_handles(i)),...
                    'x_tick_decimal_places',0, ...
                    'y_tick_decimal_places',0,...
                    'y_axis_label',{'Pixels'},'y_label_offset',-0.4,...
                    'x_label_offset',-0.30,...
                    'tick_font_size',9,...
                    'label_font_size',9,...
                    'x_axis_label',{'Opt. Density'},...
                    'x_ticks', x_ticks,...
                    'y_ticks',[1 length(summary(i).x)],...
                    'title_font_size', 9, ...
                    'title_y_offset',1.2,...
                    'y_axis_off',1)
            end

            for i = 1:length(back_fit_handles)
                
                x_pow = ceil(log10(max(summary(i).x)));
                x_tick_rounder = 10^(x_pow - 1);
                x_t_end = ceil(max(summary(i).x)/x_tick_rounder)*x_tick_rounder;
                x_t_mid = round(x_t_end/2);
                x_ticks = [0 x_t_mid x_t_end];

                improve_axes('axis_handle',sp(back_fit_handles(i)),...
                    'x_tick_decimal_places',0, ...
                    'y_tick_decimal_places',0,...
                    'y_axis_off',0,...
                    'x_label_offset',-0.30,...
                    'tick_font_size',9,...
                    'label_font_size',9,...
                    'y_axis_label',{'Pixel'},...
                    'x_axis_label',{'Opt. Density'},...
                    'x_ticks',x_ticks,...
                    'gui_scale_factor',0)
            end

            figure_export('output_file_string', app.box_layout_fname, ...
                'output_type', 'png');

            if app.RSquaredPlotCheckBox.Value
                            p = initialise_publication_quality_figure( ...
                'no_of_panels_wide', 1, ...
                'no_of_panels_high', 1, ...
                'top_margin', 0.2, ...
                'bottom_margin', 0, ...
                'right_margin', 1, ...
                'individual_padding', 1, ...
                'left_pads', repmat(0.35,[1 1]), ...
                'right_pads', repmat(0.15,[1 1]), ...
                'axes_padding_top', 0.2, ...
                'axes_padding_bottom',0.5, ...
                'panel_label_font_size', 0, ...
                'figure_handle',2,'x_to_y_axes_ratio',5);

            figure(2)
            cla
            for i = 1 : length(im_handles)
                i_name = sprintf('box_%i',i);
                insets.(i_name) = summary(i).inset;
                plot(i, summary(i).r_squared,'bo')
                hold on
                xlabel('Box No')
                ylabel('R-Squared')

            end
            hold on
            rs = plot(1:length(im_handles),0.9*ones(1,length(im_handles)),':r', ...
                'LineWidth',2);
            rs_label{1} = sprintf('R^2 = 0.9');
            xlim([0.9 n*1.1])
            xticks([1:n])
            ylim([0 1.05])

            legendflex(rs, rs_label, ...
                'xscale',0.15, ...
                'anchor',{'ne','ne'}, ...
                'buffer',[0 -30], ...
                'padding',[1 1 2], ...
                'FontSize',9, ...
                'text_y_padding', -0.2);

            improve_axes('axis_handle',p,'x_axis_label', 'Box No',...
                'y_axis_label','R-Squared', 'x_tick_decimal_places',0, ...
                'y_tick_decimal_places',0,...
                'x_ticks',[1:n],...
                'gui_scale_factor',0.1,...
                'x_label_offset',-0.35,...
                'y_label_offset',-0.10)
            
            figure_export('output_file_string', app.r_squared_fname, ...
                'output_type', 'png');

            end

            delete(app)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FittingOptionsUIFigure = uifigure('Visible', 'off');
            app.FittingOptionsUIFigure.Name = 'Fitting Options';
            app.NumberofbandsDropDownLabel = uilabel(app.FittingOptionsUIFigure);
            app.NumberofbandsDropDownLabel.Enable = 'off';
            app.NumberofbandsDropDownLabel.Text = 'Number of bands';
            app.NumberofbandsDropDown = uidropdown(app.FittingOptionsUIFigure);
            app.NumberofbandsDropDown.Items = {'1', '2', '3'};
            app.NumberofbandsDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofbandsDropDownValueChanged, true);
            app.NumberofbandsDropDown.Enable = 'off';
            app.NumberofbandsDropDown.Value = '1';
            app.FirstBandLabel = uilabel(app.FittingOptionsUIFigure);
            app.FirstBandLabel.Enable = 'off';
            app.FirstBandLabel.Text = 'First (Bottom) Band Label';
            app.FirstBand = uieditfield(app.FittingOptionsUIFigure, 'text');
            app.FirstBand.Enable = 'off';
            app.SecondBandLabel = uilabel(app.FittingOptionsUIFigure);
            app.SecondBandLabel.Enable = 'off';
            app.SecondBandLabel.Text = 'Second (Middle) Band Label';
            app.SecondBand = uieditfield(app.FittingOptionsUIFigure, 'text');
            app.SecondBand.Enable = 'off';
            app.ThirdBandLabel = uilabel(app.FittingOptionsUIFigure);
            app.ThirdBandLabel.Enable = 'off';
            app.ThirdBandLabel.Text = 'Third (Top) Band Label';
            app.ThirdBand = uieditfield(app.FittingOptionsUIFigure, 'text');
            app.ThirdBand.Enable = 'off';
            app.RSquaredPlotCheckBox = uicheckbox(app.FittingOptionsUIFigure);
            app.RSquaredPlotCheckBox.Enable = 'off';
            app.RSquaredPlotCheckBox.Text = 'R - Squared Plot';
            app.SelectOutputFolderButton = uibutton(app.FittingOptionsUIFigure, 'push');
            app.SelectOutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFolderButtonPushed, true);
            app.SelectOutputFolderButton.Text = 'Select Output Folder';
            app.OutputPathField = uieditfield(app.FittingOptionsUIFigure, 'text');
            app.OutputPathField.Editable = 'off';
            app.GenerateSummaryPlotsButton = uibutton(app.FittingOptionsUIFigure, 'push');
            app.GenerateSummaryPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSummaryPlotsButtonPushed, true);
            app.GenerateSummaryPlotsButton.Text = 'Generate Summary Plots';
            app.FigureFileNameEditFieldLabel = uilabel(app.FittingOptionsUIFigure);
            app.FigureFileNameEditFieldLabel.Text = 'Figure File Name';
            app.FigureFileNameField = uieditfield(app.FittingOptionsUIFigure, 'text');
            app.FigureFileNameField.ValueChangedFcn = createCallbackFcn(app, @FigureFileNameFieldValueChanged, true);
            % Show the figure after all components are created
            app.FittingOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SummaryPlotWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FittingOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FittingOptionsUIFigure)
        end
    end
end